classdef v5_exam3_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LogTextAreaLabel            matlab.ui.control.Label
        LogTextArea                 matlab.ui.control.TextArea
        ControlPanel                matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        Evscontrol                  matlab.ui.container.TabGroup
        EV1Tab                      matlab.ui.container.Tab
        TrimPanel_1                 matlab.ui.container.Panel
        TrimValueLabel_1            matlab.ui.control.Label
        LeftTrimButton_1            matlab.ui.control.Button
        RightTrimButton_1           matlab.ui.control.Button
        PlatooningDistanceLabel     matlab.ui.control.Label
        PlatooningDistanceSlider_1  matlab.ui.control.Slider
        BaseSpeedLabel              matlab.ui.control.Label
        BaseSpeedSlider_1           matlab.ui.control.Slider
        EV2Tab                      matlab.ui.container.Tab
        TrimPanel_2                 matlab.ui.container.Panel
        TrimValueLabel_2            matlab.ui.control.Label
        LeftTrimButton_2            matlab.ui.control.Button
        RightTrimButton_2           matlab.ui.control.Button
        BaseSpeedSlider_2Label      matlab.ui.control.Label
        BaseSpeedSlider_2           matlab.ui.control.Slider
        PlatooningDistanceSlider_2Label  matlab.ui.control.Label
        PlatooningDistanceSlider_2  matlab.ui.control.Slider
        EV3Tab                      matlab.ui.container.Tab
        TrimPanel_3                 matlab.ui.container.Panel
        TrimValueLabel_3            matlab.ui.control.Label
        LeftTrimButton_3            matlab.ui.control.Button
        RightTrimButton_3           matlab.ui.control.Button
        PlatooningDistanceSlider_3Label  matlab.ui.control.Label
        PlatooningDistanceSlider_3  matlab.ui.control.Slider
        BaseSpeedSlider_3Label      matlab.ui.control.Label
        BaseSpeedSlider_3           matlab.ui.control.Slider
        EV4Tab                      matlab.ui.container.Tab
        TrimPanel_4                 matlab.ui.container.Panel
        TrimValueLabel_4            matlab.ui.control.Label
        LeftTrimButton_4            matlab.ui.control.Button
        RightTrimButton_4           matlab.ui.control.Button
        PlatooningDistanceSlider_4Label  matlab.ui.control.Label
        PlatooningDistanceSlider_4  matlab.ui.control.Slider
        BaseSpeedSlider_4Label      matlab.ui.control.Label
        BaseSpeedSlider_4           matlab.ui.control.Slider
        EV5Tab                      matlab.ui.container.Tab
        BaseSpeedSlider_5Label      matlab.ui.control.Label
        BaseSpeedSlider_5           matlab.ui.control.Slider
        TimeTag_EV5                 matlab.ui.control.Label
        C1Tag_EV5                   matlab.ui.control.Label
        C2Tag_EV5                   matlab.ui.control.Label
        TimeLabel_EV5               matlab.ui.control.Label
        C1Label_EV5                 matlab.ui.control.Label
        C2Label_EV5                 matlab.ui.control.Label
        EV6Tab                      matlab.ui.container.Tab
        BaseSpeedSlider_6Label      matlab.ui.control.Label
        BaseSpeedSlider_6           matlab.ui.control.Slider
        SpathTab                    matlab.ui.container.Tab
        DestinationDropDownLabel    matlab.ui.control.Label
        DestinationDropDown         matlab.ui.control.DropDown
        ModePanel                   matlab.ui.container.Panel
        EV1DropDownLabel            matlab.ui.control.Label
        EV1DropDown                 matlab.ui.control.DropDown
        EV2DropDownLabel            matlab.ui.control.Label
        EV2DropDown                 matlab.ui.control.DropDown
        EV3DropDownLabel            matlab.ui.control.Label
        EV3DropDown                 matlab.ui.control.DropDown
        EV4DropDownLabel            matlab.ui.control.Label
        EV4DropDown                 matlab.ui.control.DropDown
        LeaderButtonGroup           matlab.ui.container.ButtonGroup
        EV1Button                   matlab.ui.control.ToggleButton
        EV2Button                   matlab.ui.control.ToggleButton
        EV3Button                   matlab.ui.control.ToggleButton
        EV4Button                   matlab.ui.control.ToggleButton
        NoneButton                  matlab.ui.control.ToggleButton
        Connections                 matlab.ui.container.TabGroup
        ConnectionsTab              matlab.ui.container.Tab
        Switch_1                    matlab.ui.control.RockerSwitch
        Switch_2                    matlab.ui.control.RockerSwitch
        Mario                       matlab.ui.control.UIAxes
        Toad                        matlab.ui.control.UIAxes
        Switch_3                    matlab.ui.control.RockerSwitch
        Switch_4                    matlab.ui.control.RockerSwitch
        Yoshi                       matlab.ui.control.UIAxes
        Luigi                       matlab.ui.control.UIAxes
        EV1Lamp                     matlab.ui.control.Lamp
        EV2Lamp                     matlab.ui.control.Lamp
        EV3Lamp                     matlab.ui.control.Lamp
        EV4Lamp                     matlab.ui.control.Lamp
        SecondaryTab                matlab.ui.container.Tab
        Switch_5                    matlab.ui.control.RockerSwitch
        Switch_6                    matlab.ui.control.RockerSwitch
        Label_2                     matlab.ui.control.Label
        EV5Lamp                     matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
        EV6Lamp                     matlab.ui.control.Lamp
        Wario                       matlab.ui.control.UIAxes
        Bowser                      matlab.ui.control.UIAxes
        ReadingsPanel               matlab.ui.container.Panel
        SensorsPanel                matlab.ui.container.Panel
        LeftSensorLabel             matlab.ui.control.Label
        MiddleSensorLabel           matlab.ui.control.Label
        RightSensorLabel            matlab.ui.control.Label
        RightSensorValue            matlab.ui.control.Label
        UltrasonicGaugeLabel        matlab.ui.control.Label
        UltrasonicGauge             matlab.ui.control.LinearGauge
        MidLamp                     matlab.ui.control.Lamp
        LeftSensorValue             matlab.ui.control.Label
        EVDropDownLabel             matlab.ui.control.Label
        EVDropDown                  matlab.ui.control.DropDown
        battery_image               matlab.ui.control.UIAxes
        TimeTag                     matlab.ui.control.Label
        C1Tag                       matlab.ui.control.Label
        C2Tag                       matlab.ui.control.Label
        C3Tag                       matlab.ui.control.Label
        TimeLabel                   matlab.ui.control.Label
        C1Label                     matlab.ui.control.Label
        C2Label                     matlab.ui.control.Label
        C3Label                     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Num_EV = 6
        %EVs configurations
        EV_IP = {'192.168.0.101'; '192.168.0.102'; '192.168.0.103'; '192.168.0.104'};
        EV1_IP = '192.168.0.101'
        EV2_IP = '192.168.0.102'
        EV3_IP = '192.168.0.103'
        EV4_IP = '192.168.0.104'
        EV5_IP = '192.168.0.106'
        EV6_IP = '192.168.0.105'
        
        
        %Port used for normal UDP communications (commands and sensor information)
        EV_Port = [25001 25002 25003 25004 25006 25005];
        
        %Port used for parking commands
        EV_Port_Park = [25011 25012 25013 25014 25016 25015];
        
        BufferSize = 1;
        
        %EV which is monitored for C1, C2 & C3 values
        EVMonitored = 1;


        %Optitrack Configurations
        parking_box_z = [-1.3 -1.1]  %z coordinates of parking entrace
        parking_box_x = [-.5 -.3]  %x coordinate of parking entrace

        WaitingPoint = [0.13 -1.2] %waiting point coordinates
        
        
        %Coordinates of the 3 areas where the entry/exit lane and parking spots intersect
        EntrancePointsParking= [1.42 -0.97; 1.03 -1.04;0.6 -1.09] 


        %Coordinates of the parking spots
        RampParkings = [1.41 -0.13; 1 -0.28; 0.61 -0.56]
        
        %Exit point of parking zone
        ParkingExit = [1.62 -1.19]
        
        %point in the inner track where the EVs incorporate.
        ExitLane = [1.85 -0.41]


        %Object
        EV %array of EVs with its own parameters
%         Receiver
%         Sender
%         ParkingSend
%         dataindex 1 // index of the array for placing information
%         mode_operation 0 // path tracking(0)/Platooning(1)/parking(2) for EVs. Default mode Path trackig
%         Trim_value 10  // Allows the robot to change path in an intersection. Sent value 10, display value 0
%         position
%         yaw
%         ParkingEntranceTimer //Controls the PARKING function
%         Status //Status of parking flow
%         BaseSpeed
%         PlatooningDistance
%         parkingsound //Flag that ensures parking sound to be played only once each time

        %UDP communications
        Optitrack
        Optitrack_Port = 1511;
        Optitrack_IP = '192.168.0.25';
        
        %auxiliar
        start_stop %1 order to start, 0 order to stop
        Leader = [0 0 0 0];
        ForwardBaseSpeed = 60;
        BoxTolerance = 0.1; %Tolerance for parking coordinates. When applied to a coordinate it creates a "box"
        AngleTolerance = 4; %Tolerance for parking yaw
        key_1 %Variables for ciber-security
        key_2 
        
        
        %flags
        running
        LogIndex = 1
        Log
        EV_readings_display %EV that is being displayed in GUI
        SomeoneIsParking = 0;
        ParkingSpots = [0 0 0];
        EVConnected = [0 0 0 0 0 0];
        distance_1
        distance_2
        new_yaw

        %short path
        SPstatus = 1;
        NodeFlag
        NumNodes
        node_path
        angle_path
        SPEV = 1;
        CoordinatesNodes=[2.2,-2.12; %1
            0,0;%2
            0,0;%3
            -.72,-1.81;%4
            -.69,-2.21;%5
            0.71,-3.38;%6
            1.88,-2.54;%7
            1.87,-2.02;%8
            1.87,-1.77;%9
            1.82,-1.3;%10
            -0.38,-1.4;%11
            -0.34,-1.86;%12
            -0.37,-2.13;%13
            -.37,-2.57;%14
            2.07,-1.92;%15
            -.55,-1.98;%16
            0.68,-3.04;%17
            0.75, 0.17];%18


        %Sensor data
        RLI
        timestamp
        Ultrasonic
        Battery
        
        %timer
        SensorTimer %Updates sensor values from EVs
        OptitrackTimer %Updates Optitrack information and refreshes Map
        ShortPathTimer
        
        % Map Display
        MapFig
        EvFig
        Ev1Fig
        Ev2Fig
        Ev3Fig
        Ev4Fig
        Ev5Fig
        Ev6Fig
        
        mapsc_x =[1.2,-3.8];
        
        mapsc_z = [-1.1,2.5];


    end
    
    methods (Access = public)
        function ConnectEV(app,EV, EV_IP) %Creates UDP objects to send and receive
                    %Receiver
                    try
                    app.EV(EV).Receiver = dsp.UDPReceiver('LocalIPPort', app.EV_Port(EV));
                    app.EV(EV).Receiver.RemoteIPAddress= EV_IP;
                    app.EV(EV).Receiver.ReceiveBufferSize = app.BufferSize;
                    setup(app.EV(EV).Receiver);
                    catch
                        disp('Error')
                        message = sprintf('Setup EV%d Receiver failed',EV);
                        updateLog(app,message);
                    end
                    
                    %Sender
                    try
                    app.EV(EV).Sender = dsp.UDPSender('RemoteIPPort', app.EV_Port(EV));
                    app.EV(EV).Sender.RemoteIPAddress= EV_IP;
                    catch
                        message = sprintf('Setup EV%d Sender failed',EV);
                        updateLog(app,message);
                    end
                    %ParkingSender
                    try
                    app.EV(EV).ParkingSend = dsp.UDPSender('RemoteIPPort',app.EV_Port_Park(EV));
                    app.EV(EV).ParkingSend.RemoteIPAddress= EV_IP;
                    catch
                        message = sprintf('Setup EV%d ParkingSend failed',EV);
                        updateLog(app,message);
                    end
                    pause(0.001)
                    
                    app.EVConnected(EV) = 1;
                 
        end
    
        function DisconnectEV(app,EV) %Disconnects UDP objects
                app.EVConnected(EV) = 0;
                try
                    release(app.EV(EV).Receiver);
                catch
                    message = sprintf('Disconnect EV%d Receiver failed',EV);
                    updateLog(app,message);
                end
                try
                release(app.EV(EV).Sender);
                catch
                    message = sprintf('Disconnect EV%d Sender failed',EV);
                    updateLog(app,message);
                end
                try
                release(app.EV.(EV).ParkingSend);
                catch
                    message = sprintf('Disconnect EV%d ParkingSend failed',EV);
                    updateLog(app,message);
                end


        end
    
        % update log information in the log area, latest 5 information will be shown
        function updateLog(app,message)
            app.Log{app.LogIndex} = message;
            try
                if app.LogIndex <= 5
                    app.LogTextArea.Value = app.Log(1:app.LogIndex);
                else
                    app.LogTextArea.Value = app.Log(app.LogIndex-5:app.LogIndex);
                end
                app.LogIndex = app.LogIndex + 1;
            catch
            end
        end
    
        function BatteryLevel(app,battery_level) %Receives the battery percentage and changes the img according to that
            if battery_level >= 90
                %bat full
                imshow('full.jpg','Parent',app.battery_image);
                
            elseif 65 <= battery_level && battery_level < 90
                %bat 75
                imshow('75.png','Parent',app.battery_image);
                
            elseif 35 <= battery_level && battery_level < 65
                %bat 50
                imshow('50.png','Parent',app.battery_image);
                
            elseif 15 <= battery_level && battery_level <35
                %bat 25
                imshow('25.png','Parent',app.battery_image);
            elseif battery_level < 15
                %bat empty
                imshow('empty.png','Parent',app.battery_image);
            end
        end
        
        function timerSensorUpdate(app) %callback of SensorTimer timer
            %Every second this function recieves UDP packet and sets 
            %Sensor Values(UpdateSensor) and Timestamp. For each of the connected EVs
            for i = 1:1:5
                if app.EVConnected(i) == 1
                    %receive data from EV
                    dataReceived = app.EV(i).Receiver(); 
                    %increase the index of the vectors. for i=1 values will be 0
                    app.EV(i).dataindex = app.EV(i).dataindex + 1; 
                    %Call fnc that updates sensor values
                    UpdateSensor(app, i,dataReceived, app.EV(i).dataindex);
                    %update timestamp. Accumulated time value + time between SensorTimer ticks
                    app.timestamp(i, app.EV(i).dataindex) = app.SensorTimer.InstantPeriod + app.timestamp(i, app.EV(i).dataindex-1);
                    %avoid NaN in the first call (supposing a exact time of 1 sec)
                    if app.EV(i).dataindex == 2
                         app.timestamp(i,2) = 1;
                    end
                end
            end


        end


        function UpdateSensor(app, EV, dataReceived, i)   
                %receives vector with UDP Data and the vector index
                %UDP packet values: left sensor, color sensor, right sensor, US, left encoder, right encoder, battery level
             try
                
                %assign values to variables
                
                left_sensor = dataReceived(1);
                middle_sensor = dataReceived(2);
                right_sensor = dataReceived(3);
                US = dataReceived(4);
    %             left_encoder = dataReceived(5);
    %             right_encoder = dataReceived(6);
                battery = dataReceived(7);
                
                %update tags. Only if this EV is the one selected for display
                if EV == app.EV_readings_display
                    app.LeftSensorValue.Text= int2str(left_sensor);
                    app.RightSensorValue.Text= int2str(right_sensor);
                    app.UltrasonicGauge.Value = US;
                    BatteryLevel(app,battery);
                    
                    
                        switch middle_sensor
                            case 1 % black
                            app.MidLamp.Color = 'k';
                            case 6 % white
                            app.MidLamp.Color = 'w';  
                            case 3 % blue
                            app.MidLamp.Color = 'b';
                            case 5 % red
                            app.MidLamp.Color = 'r';
                            otherwise
                            app.MidLamp.Color = 'k';
                        end
                        
                      app.TimeTag.Text = int2str(app.timestamp(EV,end));
                end
            %Update vectors for evaluation
            
            app.RLI(EV,i) = left_sensor; %sending left sensor value
            app.Ultrasonic(EV,i) = US;


            catch
%                 updateLog(app,'Error in sensor communication.');
            end
        end
    
        function SendMessage(app,EV)
            %Sends UDP packet to theEVs. Parameters: #EV (1-4), status(Start/stop), mode(path/plat),base speed, Plat distance, trim value, leader, key 1, key 2
            
            if app.EVConnected(EV) == 1
                app.EV(EV).Sender(uint8([app.start_stop app.EV(EV).mode_operation app.EV(EV).BaseSpeed app.EV(EV).PlatooningDistance app.EV(EV).Trim_value app.Leader(EV) app.key_1 app.key_2]));
%                 app.EV(EV).Sender(uint8([app.start_stop app.EV(EV).mode_operation 60 20 app.EV(EV).Trim_value app.Leader(EV) app.key_1 app.key_2]));
                
            end
            
        end
    
        
        function [display] = LeftTrimValueChange(app,EV)
            %Decreases Trim value by 1 if Trim Value > -10
            if app.EV(EV).Trim_value > 0
                app.EV(EV).Trim_value = app.EV(EV).Trim_value - 1;    
            end
            display = int2str(app.EV(EV).Trim_value-10);
        end
        
        function [display] = RightTrimValueChange(app,EV)
            %Increases Trim value by 1 if Trim Value < 10
            if app.EV(EV).Trim_value < 20
                app.EV(EV).Trim_value = app.EV(EV).Trim_value + 1;
            end
            display = int2str(app.EV(EV).Trim_value-10);
        end
    
        function UpdateOptitrack(app)
            %receives information from optitrack and updates Map 
            try
                DataOptitrack = app.Optitrack(); %Receive trhough UDP communication
                
                [position,orientation] = Depacket(DataOptitrack);
  
                j=1;
            catch
            end
            for Ev=1:4
                try
                    if app.EVConnected(Ev) == 1 && position(j,1) ~= 0
                        app.EV(Ev).position(1) = position(j,1);
                        app.EV(Ev).position(2) = position(j,3);
                        %Calculate yaw in degrees -> 0-360º
                        qx = orientation(j,1);
                        qy= orientation(j,2);
                        qz= orientation(j,3);
                        qw = orientation(j,4);
                        
                        yaw = rad2deg(atan2(2.0*(qy*qw + qx*qz), 1 - 2*(qy*qy + qz*qz)));
                        
                        if yaw <=0
                            app.EV(Ev).yaw = 360+yaw;
                        else
                            app.EV(Ev).yaw = yaw;
                        end
                    
                        j=j+1;   
                    end
                catch
                end
            end
            UpdateMap(app)

        end
    
        function EVGoStraight(app,EV,target_pos,BaseSpeed) %Ev goes straight to the target point
            
            current_pos = [app.EV(EV).position(1) app.EV(EV).position(2)]; %current position
            
            v= target_pos-current_pos; %vector from current position to target position
            theta= rad2deg(atan(v(1)/v(2))); %Angle of the vector in respect to Z axis (origen)
                    if theta > 0
                        if v(2) <= 0 && v(1) <= 0
                            theta_f = 180+theta;
                        else
                            theta_f = theta;
                        end
                    else
                        if v(1)<=0 && v(2) >= 0
                            theta_f = 360+theta;
                        else
                            theta_f = 180+theta;
                        end
                    end
             parkingsend(1) = int16(app.EV(EV).yaw); %Current yaw
             parkingsend(2) = int16(theta_f); %objective yaw
             parkingsend(3) = int16(BaseSpeed); %base speed
             parkingsend(4) = int16(1); %No brake
             
             app.EV(EV).ParkingSend(parkingsend); %Send trough UDP        
        end
    
        function EVGoBack(app,EV,target_pos,BaseSpeed) %Ev goes straight to the target point
            
            current_pos = [app.EV(EV).position(1) app.EV(EV).position(2)];
            
            v= target_pos-current_pos; %vector from current position to target position
            theta= rad2deg(atan(v(1)/v(2))); %Angle of the vector in respect to Z axis (origen)
                    if theta > 0
                        if v(2) <= 0 && v(1) <= 0
                            theta_f = 180+theta;
                        else
                            theta_f = theta;
                        end
                    else
                        if v(1)<=0 && v(2) >= 0
                            theta_f = 360+theta;
                        else
                            theta_f = 180+theta;
                        end
                    end
                    theta_f = theta_f +180;
                    if  theta_f >= 360
                        theta_f = theta_f - 360;
                    end
             parkingsend(1) = int16(app.EV(EV).yaw); %Current yaw
             parkingsend(2) = int16(theta_f); %Target yaw
             parkingsend(3) = int16(BaseSpeed); %Base speed
             parkingsend(4) = int16(1); %No brake
             
             app.EV(EV).ParkingSend(parkingsend); %Send trough UDP              
        end
    
        function EVRotate(app,EV,target_yaw) %EV turns in spot to the target yaw
            
            parkingsend(1) = int16(app.EV(EV).yaw); %Current yaw
            parkingsend(2) = int16(target_yaw); %Target yaw
            parkingsend(3) = int16(0); %Base Speed
            parkingsend(4) = int16(1); %No brake
            app.EV(EV).ParkingSend(parkingsend);
            

        end
    
        function EVStop(app,EV) %Sends order to stop the EV, both rotation and movement
            parkingsend(1) = int16(app.EV(EV).yaw); %Current yaw
            parkingsend(2) = int16(app.EV(EV).yaw); %Target yaw
            parkingsend(3) = int16(0); %Base Speed
            parkingsend(4) = int16(0); %No brake
            
            app.EV(EV).ParkingSend(parkingsend);


        end
    
        function [status] = CheckParkingInbox(app,EV) %Checks if the EV is in the entry point to parking
            
                if app.EV(EV).position(2) >= app.parking_box_z(1) && app.EV(EV).position(2) <= app.parking_box_z(2) && app.EV(EV).position(1) >= app.parking_box_x(1) && app.EV(EV).position(1) <= app.parking_box_x(2)
                    
                    status = 2; %Status 2 will go to the next STAGE in PARKING
                    
                    app.EV(EV).mode_operation = 2; %If it ins inside, mode changes to parking
                   
                    EVStop(app,EV);
                   
                    try
                    message = sprintf('Mode EV%d Parking',EV);
                    updateLog(app,message);

                    SendMessage(app,EV);
                    catch
                        message = sprintf('Error');
                        updateLog(app,message);
                    end


                else
                    status = 1; % Status 1 will maintain current stage in PARKING
                    

                end
                
        end
    
        function PARKING(app,EV,status) %Parking flow. Depending on the "status" of the EV, different
            % orders are executed
%             message = sprintf('Status EV%d %d ',EV, app.EV(EV).Status);
%             updateLog(app,message);

            switch status
                
                case 0 %Not in parking mode
                    
                    stop(app.EV(EV).ParkingEntranceTimer); %Stop the parking timer
                    
                    SendMessage(app,EV);
                    
                case 1 %Check if EV is in the entry point
                    app.EV(EV).Status = CheckParkingInbox(app,EV);
                    
                case 2 %In parking Entrance. Rotate until facing the parking zone
                    
%                     message = sprintf('Status EV%d 2',EV);
%                     updateLog(app,message);
                    
                    if app.EV(EV).yaw >90+app.AngleTolerance || app.EV(EV).yaw <90-app.AngleTolerance
                        EVRotate(app,EV,90); %If the target angle is not reached, keep rotating.
%                         message = sprintf('angle %d ',app.EV(EV).yaw);
%                         updateLog(app,message);
                    else
                        app.EV(EV).Status = 3; %If the target angle is reached, go to next stage.
                        EVStop(app,EV);
                    end
                    
                    
                case 3  %Go to the waiting point. The EV will stop if any other EV is parking
                    %or deparking
                    
%                     message = sprintf('Status EV%d 3',EV);
%                     updateLog(app,message);

                    if app.EV(EV).position(1) > app.WaitingPoint(1)-app.BoxTolerance && app.EV(EV).position(1) < app.WaitingPoint(1)+app.BoxTolerance && app.EV(EV).position(2) > app.WaitingPoint(2)-app.BoxTolerance && app.EV(EV).position(2) < app.WaitingPoint(2)+app.BoxTolerance
                        %If the Waiting point is reached, the EV stops
                        EVStop(app,EV);
                        disp(sum(app.ParkingSpots))
                        if sum(app.ParkingSpots) < 3
                            if app.SomeoneIsParking == 0 %If no one is parking, looks for the first available parking spot
                                for i = 1:3
                                    if app.ParkingSpots(i) == 0 %sets the first available spot as parking for this EV
                                        app.ParkingSpots(i) = 1;
                                        app.EV(EV).ParkingSpot = i;
                                        app.EV(EV).EntrancePoint = [app.EntrancePointsParking(i,1) app.EntrancePointsParking(i,2)];
                                        app.EV(EV).ParkingRamp = [app.RampParkings(i,1) app.RampParkings(i,2)];
                                        break
                                    end
                                end
                                app.EV(EV).Status = 4; %As no one is parking, the EV goes to next stage
                            end
                        end
                    else
                        %If waiting point not  reached, keep going straight
                        EVGoStraight(app,EV,app.WaitingPoint, app.ForwardBaseSpeed) 
                        
                    end
                    
                case 4 %Goes to the perpendicular point of the assigned parking spot
                    
%                     message = sprintf('Status EV%d 4',EV);
%                     updateLog(app,message);

                    app.SomeoneIsParking = 1; %Update flag. Now this EV is parking
                    
                    
                    if app.EV(EV).position(1) > app.EV(EV).EntrancePoint(1)-app.BoxTolerance && app.EV(EV).position(1) < app.EV(EV).EntrancePoint(1)+app.BoxTolerance && app.EV(EV).position(2) > app.EV(EV).EntrancePoint(2)-app.BoxTolerance && app.EV(EV).position(2) < app.EV(EV).EntrancePoint(2)+app.BoxTolerance
                        app.EV(EV).Status = 5; %If the Entrance point is reached, go to next stage
                        EVStop(app,EV);
                        
                    else
                        %If entrance point is not reached, keep going straight
                        EVGoStraight(app,EV,app.EV(EV).EntrancePoint, app.ForwardBaseSpeed)
                    end
                    
                case 5 %Rotate to face parking spot
%                     message = sprintf('Status EV%d 5',EV);
%                     updateLog(app,message);

                    if app.EV(EV).yaw <app.AngleTolerance && app.EV(EV).yaw >=0   || app.EV(EV).yaw >360-app.AngleTolerance && app.EV(EV).yaw <360
                        app.EV(EV).Status = 6; %If angle is reached, go to next stage
                        EVStop(app,EV);
                    else   
                        EVRotate(app,EV,0); %Otherwise keep rotating.
                    end
                    
                case 6 %Go straight until Parking spot
                    
%                     message = sprintf('Status EV%d 6',EV);
%                     updateLog(app,message);
                    if app.EV(EV).position(1) > app.EV(EV).ParkingRamp(1)-app.BoxTolerance && app.EV(EV).position(1) < app.EV(EV).ParkingRamp(1)+app.BoxTolerance && app.EV(EV).position(2) > app.EV(EV).ParkingRamp(2)-app.BoxTolerance && app.EV(EV).position(2) < app.EV(EV).ParkingRamp(2)+app.BoxTolerance
                        app.EV(EV).Status = 7; %If spot is reached, go to next stage
                        EVStop(app,EV);
                    else
                        %Otherwise keep going straight
                        EVGoStraight(app,EV,app.EV(EV).ParkingRamp, app.ForwardBaseSpeed)
                    end
                case 7 %Enter in path tracking mode, to align with parking spot
                    
%                     message = sprintf('Status EV%d 7',EV);
%                     updateLog(app,message);

                    app.SomeoneIsParking = 0; %Out of parking area. This EV is no longer parking

                    try
                        app.EV(EV).mode_operation = 0; %change mode to path tracking
                        SendMessage(app,EV);
                        if app.EV(EV).parkingsound == 0
                            PlaySound(app,'stage_clear.wav');
                            app.EV(EV).parkingsound = 1;
                        end
                        stop(app.EV(EV).ParkingEntranceTimer);
%                         message = sprintf('Mode path tracking, mode %d',app.EV(EV).mode_operation);
%                         updateLog(app,message); 
                    catch
%                        message = sprintf('Mode not path tracking, mode %d',app.EV(EV).mode_operation);
%                        updateLog(app,message); 
                    end
                    
                case 8 %Order to depark sent -> Mode changed from parking to path tracking
                    
%                     message = sprintf('Status EV%d 8',EV);
%                     updateLog(app,message);
                    app.EV(EV).parkingsound = 0;
                    if app.SomeoneIsParking == 1 && app.EV(EV).mode_operation == 0
                        EVStop(app,EV) %Wait until parking area is clear
                    else
                        app.EV(EV).mode_operation = 2; %Return to parking mode
                        SendMessage(app,EV);
                        app.SomeoneIsParking = 1; %Update flag, now this EV is parking
                        
                        
                        if app.EV(EV).position(1) > app.EV(EV).EntrancePoint(1)-app.BoxTolerance && app.EV(EV).position(1) < app.EV(EV).EntrancePoint(1)+app.BoxTolerance && app.EV(EV).position(2) > app.EV(EV).EntrancePoint(2)-app.BoxTolerance && app.EV(EV).position(2) < app.EV(EV).EntrancePoint(2)+app.BoxTolerance
                            app.EV(EV).Status = 9; %if target point is reached go to next stage
                            EVStop(app,EV);
%                             message = sprintf('Reached');
%                             updateLog(app,message);
                        else
%                             message = sprintf('Going Backwards to Entrance Point');
%                             updateLog(app,message);
                            %Otherwise reverse to entrance point
                            EVGoBack(app,EV,app.EV(EV).EntrancePoint, -30)
                        end
                    end
                    
                case 9 %rotate to face exit point
                    
%                     message = sprintf('Status EV%d 9',EV);
%                     updateLog(app,message);
                    
                    if app.EV(EV).yaw >90+app.AngleTolerance || app.EV(EV).yaw <90-app.AngleTolerance
                        EVRotate(app,EV,90); %If angle is not reached keep rotating
                    else
                        app.EV(EV).Status = 10; %Angle reached. Go to next stage
                        EVStop(app,EV);
                    end
                    
                case 10 %Go to parking exit
                    
%                     message = sprintf('Status EV%d 10',EV);
%                     updateLog(app,message);

                    if app.EV(EV).position(1) > app.ParkingExit(1)-app.BoxTolerance && app.EV(EV).position(1) < app.ParkingExit(1)+app.BoxTolerance && app.EV(EV).position(2) > app.ParkingExit(2)-app.BoxTolerance && app.EV(EV).position(2) < app.ParkingExit(2)+app.BoxTolerance
                        app.EV(EV).Status = 11; %Exit reached. Go to next stage
                        EVStop(app,EV);
                        app.SomeoneIsParking = 0; %Parking area cleared
                        
                        app.ParkingSpots(app.EV(EV).ParkingSpot)=0; %parking spot used cleared
                    else
                        %otherwise keep going straight
                        EVGoStraight(app,EV,app.ParkingExit, app.ForwardBaseSpeed)
                    end

                case 11 %Incorporate to inner loop
%                     message = sprintf('Status EV%d 11',EV);
%                     updateLog(app,message);
                    if app.EV(EV).position(1) > app.ExitLane(1)-app.BoxTolerance && app.EV(EV).position(1) < app.ExitLane(1)+app.BoxTolerance && app.EV(EV).position(2) > app.ExitLane(2)-app.BoxTolerance && app.EV(EV).position(2) < app.ExitLane(2)+app.BoxTolerance
                        app.EV(EV).Status = 12; %target reached, next stage
                        EVStop(app,EV);
                    else
                        %otherwise keep going
                        EVGoStraight(app,EV,app.ExitLane, app.ForwardBaseSpeed)
                    end
                case 12 %Rotate to face road
                    
%                     message = sprintf('Status EV%d 12',EV);
%                     updateLog(app,message);
                    
                    if  app.EV(EV).yaw <app.AngleTolerance && app.EV(EV).yaw >=0   || app.EV(EV).yaw >360-app.AngleTolerance && app.EV(EV).yaw <360
                        app.EV(EV).Status = 0; %Angle reached, END OF PARKING
                        EVStop(app,EV);
                        app.EV(EV).mode_operation = 0; %Mode set to path tracking
                        SendMessage(app,EV)
                        stop(app.EV(EV).ParkingEntranceTimer); %STOPS parking timer
                    else   
                        EVRotate(app,EV,0); %otherwise keep rotating.
                    end
                    
                    
            end
            
        end
    
        function status = SPcheckbox(app,EV, point_x, point_z)
              
              if app.EV(EV).position(1) > point_x-app.BoxTolerance && app.EV(EV).position(1) < point_x+app.BoxTolerance && app.EV(EV).position(2) > point_z-app.BoxTolerance && app.EV(EV).position(2) < point_z+app.BoxTolerance
                 EVStop(app,EV);
                 if app.SPstatus == 2
                    status = 3;
                 else
                    status = 5;
                 end
              else
                  if app.SPstatus == 2
                      status = 2;
                  else
                      status = 4;
                  end
              end
        end
    
        function SHORT_PATH(app,EV,status)
%             message = sprintf('Status sp %d ',app.SPstatus);
%             updateLog(app,message);
            
            switch status
                case 1 %find shortest path
                    %find source node. Node closest to current EV position
                    [source, app.distance_1] = find_source_node(app.EV(EV).position(1),app.EV(EV).position(2));

                    %Retreive destination from the GUI
                    destination = str2num(app.DestinationDropDown.Value);
                    
                    %Find sequence of nodes and angle to rotate on each node
                    [app.node_path,app.angle_path]=shortest_path(source,destination);
                    
                    %flags
                    app.NumNodes = length(app.node_path);
                    app.NodeFlag = 1;
                    
                    %Goes to Status 6
                    app.SPstatus = 6;
                    
                case 2 %Path tracking to point
                    app.EV(EV).mode_operation = 0; %path tracking mode
                    SendMessage(app,EV);
                    %checks if the EV reaches its destination (Next node)
                    app.SPstatus = SPcheckbox(app,EV,app.CoordinatesNodes(app.node_path(app.NodeFlag),1),app.CoordinatesNodes(app.node_path(app.NodeFlag),2));
                
                case 3 %Rotate if needed
                    %Remote control mode
                    app.EV(EV).mode_operation = 2;
                    SendMessage(app,EV);
                    
                    if app.angle_path(app.NodeFlag) == 0 %if the angle to face is 0 degrees
                        %Check if EV has correct yaw
                        if app.EV(EV).yaw <app.AngleTolerance && app.EV(EV).yaw >=0   || app.EV(EV).yaw >360-app.AngleTolerance && app.EV(EV).yaw <360     
                            EVStop(app,EV);
                            
                            if app.NodeFlag == app.NumNodes-1
                                app.SPstatus = 4; %If next node is the last node, go to status 4
                            else
                                app.SPstatus = 2; %If not, go again to status 2
                                app.NodeFlag = app.NodeFlag + 1;
                            end
                        else %If the target yaw is not reached, keep rotating
                            EVRotate(app,EV, app.angle_path(app.NodeFlag));
                        end
                    elseif app.angle_path(app.NodeFlag) == 500 %arbitrary value. In case the path is only of 1 node.
                        app.SPstatus = 4;
                    else %if the angle to face is not 0
                        %Check if EV has correct yaw
                        if app.EV(EV).yaw >app.angle_path(app.NodeFlag)+app.AngleTolerance || app.EV(EV).yaw <app.angle_path(app.NodeFlag) - app.AngleTolerance     
                            %If the target yaw is not reached, keep rotating
                            EVRotate(app,EV, app.angle_path(app.NodeFlag));
                            
                        else
                            EVStop(app,EV);
                            if app.NodeFlag == app.NumNodes-1
                                app.SPstatus = 4; %If next node is the last node, go to status 4
                            else
                                app.SPstatus = 2; %If not, go again to status 2
                                app.NodeFlag = app.NodeFlag + 1;
                            end
                        end
                    end
                case 4 %path tracking to final point
                    app.EV(EV).mode_operation = 0; %path tracking mode
                    SendMessage(app,EV);
                    %checks if the EV reaches its destination (Next node)
                    app.SPstatus = SPcheckbox(app,EV,app.CoordinatesNodes(app.node_path(end),1),app.CoordinatesNodes(app.node_path(end),2));
                case 5 %Destination reached
                    app.EV(EV).mode_operation = 2; %Remote control mode
                    SendMessage(app,EV);
                    EVStop(app,EV); %Stop EV
                    stop(app.ShortPathTimer); %Stop periodic execution of SHORT_PATH
                    
                case 6 %Check if EV is facing closest node or opposite direction
                    pause(0.5) %Waits half a second for the new position
                    
                    %Calculates again distance to closest node
                    [~, app.distance_2] = find_source_node(app.EV(EV).position(1),app.EV(EV).position(2));

                    
                    if app.distance_2 > app.distance_1 %Compares the two distances
                        %If the second value is greater, EV is going away from closest node.
                        %The EV would need to turn 180º to face node
                        app.new_yaw = app.EV(EV).yaw +180;
                        if app.new_yaw > 360
                            app.new_yaw = 360 - app.new_yaw;
                        end
                        disp(app.new_yaw)
                        
                        app.SPstatus = 7; %Goes to status 7
                    else
                        app.SPstatus = 2; %Goes to status 2
                    end
                    
                case 7 %Turns 180º to face closest node
                    
                    app.EV(EV).mode_operation = 2; %Remote control mode
                    SendMessage(app,EV);
                    if app.new_yaw > 360-app.AngleTolerance || app.new_yaw < app.AngleTolerance
                        if  app.EV(EV).yaw > app.AngleTolerance && app.EV(EV).yaw <360-app.AngleTolerance   
                            EVRotate(app,EV,app.new_yaw);
                        else
                            app.SPstatus = 2;
                            EVStop(app,EV)
                        end
                        
                    else
                        if app.EV(EV).yaw >app.new_yaw +app.AngleTolerance || app.EV(EV).yaw <app.new_yaw - app.AngleTolerance     
                            EVRotate(app,EV,app.new_yaw);
                        else
                            app.SPstatus = 2;
                            EVStop(app,EV)
                        end
                    end
                    
            
            end
            
        end
    
        function UpdateMap(app) %Updates map with the new information form optitrack


            %Redraws map
            imagesc( app.mapsc_x,app.mapsc_z,app.MapFig,'Parent',app.UIAxes);


            hold(app.UIAxes,'on')
            for ev = 1:4 %Selects correct image
                try
                    if app.EVConnected(ev) == 1
                        
                        switch ev
                            case 1
                                figure = app.Ev1Fig;
                            case 2 
                                figure = app.Ev2Fig;
                            case 3
                                figure = app.Ev3Fig;
                            case 4
                                figure = app.Ev4Fig;
                        end
                        
                        %Draws EV
                        imagesc( [app.EV(ev).position(2),app.EV(ev).position(2)-0.2],[app.EV(ev).position(1),app.EV(ev).position(1)+0.2],figure,'Parent',app.UIAxes);
                        
                    end
                catch
                end
            end
            hold(app.UIAxes,'off')


        end
    
        function ChangeMode(app,ev,newmode) %Change in mode of operation
             switch newmode
                case "Platooning"
                    app.EV(ev).mode_operation = 1;
                    message = sprintf('Mode EV%d Platooning',ev);
                    updateLog(app, message);
                    app.EV(ev).Status= 0; %parking status 0
                    
                case "Path Tracking"
                    stop(app.ShortPathTimer);
                    switch app.EV(ev).Status
                        case 7
                           app.EV(ev).Status = 8; %If the EV is parked, it needs to depark.
                           %Sets stage to 8
                           start(app.EV(ev).ParkingEntranceTimer);
                           
                           
                        otherwise
                            stop(app.EV(ev).ParkingEntranceTimer);
                            app.EV(ev).mode_operation = 0;
                            message = sprintf('Mode EV%d Path Tracking',ev);
                            updateLog(app,message);
                            app.EV(ev).Status = 0; %parking status 0
                    end
                    
                case "Parking"
                    app.EV(ev).Status = 1; %Starts parking flow
                    
                    message = sprintf('Mode EV%d going to parking',ev);
                    updateLog(app,message);
                    
                    start(app.EV(ev).ParkingEntranceTimer)
                case "Shortest Path"
                    app.SPstatus = 1;
                    message = sprintf('Mode EV%d shortest path',ev);
                    updateLog(app,message);
                    
                    start(app.ShortPathTimer);
                    
            end
        end
        
        function Characters(~, char, img)
            title(char, []);
            xlabel(char, []);
            ylabel(char, []);
            char.XAxis.TickLabels = {};
            char.YAxis.TickLabels = {};
            char.XLim= [0 , 1];
            char.YLim = [0, 1];
            imagesc([0,1] ,[0,1], img, 'Parent', char);
        end
        
        function PlaySound(~, file)
            try
                [y, Fs] = audioread(file);
                sound(y, Fs, 16);
            catch
            end
            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            %init values
            app.running = 0;
            
            app.MidLamp.Color = 'k';
            
            app.Log = {''};
            
            
            app.EV_readings_display = 1; %starts in EV1
            
            
            for i = 1:app.Num_EV %Initializes EV object parameters

                if i <=4
                    app.EV(i).Status = 0;
                    app.EV(i).ParkingEntranceTimer = timer('ExecutionMode','fixedRate','Period',.1);
                    app.EV(i).ParkingEntranceTimer.TimerFcn = @(obj, event)PARKING(app,i,app.EV(i).Status);
%                     app.EV(i).ParkingEntranceTimer.BusyMode = 'drop';
                    
                    
                    app.EV(i).Trim_value = 10;
                    app.EV(i).parkingsound = 0;
                end
                    
                app.EV(i).mode_operation = 0;
                 
            end
            
            app.EV(1).BaseSpeed = app.BaseSpeedSlider_1.Value;
            app.EV(2).BaseSpeed = app.BaseSpeedSlider_2.Value;
            app.EV(3).BaseSpeed = app.BaseSpeedSlider_3.Value;
            app.EV(4).BaseSpeed = app.BaseSpeedSlider_4.Value;
            app.EV(5).BaseSpeed = app.BaseSpeedSlider_5.Value;
            app.EV(6).BaseSpeed = app.BaseSpeedSlider_6.Value;
            
            
            app.EV(1).PlatooningDistance = app.PlatooningDistanceSlider_1.Value;
            app.EV(2).PlatooningDistance = app.PlatooningDistanceSlider_2.Value;
            app.EV(3).PlatooningDistance = app.PlatooningDistanceSlider_3.Value;
            app.EV(4).PlatooningDistance = app.PlatooningDistanceSlider_4.Value;
            
            
            app.TrimValueLabel_1.Text = int2str(app.EV(1).Trim_value-10);
            app.TrimValueLabel_2.Text = int2str(app.EV(2).Trim_value-10);
            app.TrimValueLabel_3.Text = int2str(app.EV(3).Trim_value-10);
            app.TrimValueLabel_4.Text = int2str(app.EV(4).Trim_value-10);
            
            imshow('NoBattery.png','Parent',app.battery_image); %initial image (nothing)
            
            %map

            app.UIAxes.Position = [405,107,499,337];
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            app.UIAxes.XLim = [app.mapsc_x(2),app.mapsc_x(1)];
            app.UIAxes.YLim = [app.mapsc_z(1),app.mapsc_z(2)];


            app.MapFig = imread('map.jpg');
            app.Ev1Fig = imread('EV1.png');
            app.Ev2Fig = imread('EV2.png');
            app.Ev3Fig = imread('EV3.png');
            app.Ev4Fig = imread('EV4.png');
            app.Ev5Fig = imread('EV5.png');
            app.Ev6Fig = imread('EV6.png');
            
            app.UIAxes.XDir = 'reverse';
            imagesc( app.mapsc_x, app.mapsc_z,app.MapFig,'Parent',app.UIAxes);
            
            
            Characters(app, app.Yoshi, app.Ev1Fig);
            Characters(app, app.Mario, app.Ev2Fig);
            Characters(app, app.Luigi, app.Ev3Fig);
            Characters(app, app.Toad, app.Ev4Fig);
            Characters(app, app.Wario, app.Ev5Fig);
            Characters(app, app.Bowser, app.Ev6Fig );


            %init timers
            app.SensorTimer = timer('ExecutionMode','fixedRate','Period',1);
            app.SensorTimer.TimerFcn = @(obj, event)timerSensorUpdate(app);
            app.SensorTimer.BusyMode = 'drop';

            
            app.OptitrackTimer = timer('ExecutionMode','fixedRate','Period',.1);
            app.OptitrackTimer.TimerFcn = @(obj, event)UpdateOptitrack(app);
            app.OptitrackTimer.BusyMode = 'drop';
            
            %ShortestPath
            app.ShortPathTimer = timer('ExecutionMode','fixedRate','Period',.05);
            app.ShortPathTimer.TimerFcn = @(obj, event)SHORT_PATH(app,app.SPEV,app.SPstatus);
            
            %Optitrack communications
            app.Optitrack= dsp.UDPReceiver('LocalIPPort', app.Optitrack_Port);
            app.Optitrack.RemoteIPAddress= app.Optitrack_IP;
            app.Optitrack.ReceiveBufferSize = app.BufferSize;
            app.Optitrack.MaximumMessageLength = 3000; 
            setup(app.Optitrack);


            %START optitrack updating
            start(app.OptitrackTimer)
            
            %Security
            try
                key = evalin('base', 'key');
                app.key_1 = key(1,1);
                app.key_2 = key(2,2);
            catch
                app.key_1 = 1;
                app.key_2 = 1;
                message = sprintf('Key recovery fail');
                updateLog(app,message);
            end
            
            %extras    
            for EV = 5:6
                app.EV(EV).mode_operation = 0;
                app.EV(EV).PlatooningDistance = 20;
                app.EV(EV).Trim_value = 0;
                app.Leader(EV) = 0;
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %The run starts. All EVs connected start moving
            app.running = 1; %executing program
            updateLog(app,'Starting programm, reading values');
            
            %restart vectors
            app.RLI = 0;
            app.timestamp = zeros(app.Num_EV,1);
            app.Ultrasonic = 0;
            
            for ev = 1:app.Num_EV
                app.EV(ev).dataindex = 1;
            end
            %start timer
            try
                start(app.SensorTimer);
            catch
            end
            
            %order to start movement
            app.start_stop = 1;
          
            PlaySound(app,'startingline.wav');
            pause(3);
            
            %Sends initial values to Evs and starts movement
            for ev = 1:app.Num_EV
%                 message = sprintf('Starting EV%d',ev);
%                 updateLog(app,message);
                SendMessage(app,ev);
            end
            
            app.Map_test = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           app.LogTextArea.Value = "";
          
           
           for i = 1:1:app.Num_EV
               try
                   release(app.EV(i).Receiver);
                   release(app.EV(i).Sender);
               catch
               end
               try
                   stop(app.EV(i).ParkingEntranceTimer)
               catch
               end
           end
          
          try
                stop(app.OptitrackTimer)
          catch
          end
          delete(app)   
            
        end

        % Value changed function: Switch_1
        function Switch_1ValueChanged(app, event)
            if app.Switch_1.Value == "On"

                    ConnectEV(app,1, app.EV1_IP)   
                    app.EV1Lamp.Color = 'g';
                    updateLog(app, 'EV1 connected.');
                    PlaySound(app,'mario.wav');


            elseif app.Switch_1.Value == "Off"

                DisconnectEV(app,1)
                
                app.EV1Lamp.Color = 'r';
                updateLog(app,'EV1 disconnected.');
                PlaySound(app,'disconnect.wav');
            end

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            if app.Switch_2.Value == "On"
                
                ConnectEV(app,2,app.EV2_IP)
                app.EV2Lamp.Color = 'g';
                updateLog(app,'EV2 connected.');
                PlaySound(app,'yoshi.wav');
                
  
            elseif app.Switch_2.Value == "Off"
                
                DisconnectEV(app,2)
                
                app.EV2Lamp.Color = 'r';
                updateLog(app,'EV2 disconnected.');
                PlaySound(app,'disconnect.wav');
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             app.running = 0; %ends run
             stop(app.SensorTimer);

             
             %deactivate EVs
             app.start_stop = 0;
             
             for ev = 1:1:app.Num_EV
                SendMessage(app,ev);
                if ev <=4
                    app.EV(ev).Trim_value = 10;
                end
             end
             %reset values
             app.LeftSensorValue.Text= "00";
             app.RightSensorValue.Text= "00";
             
             
             app.TrimValueLabel_1.Text = int2str(app.EV(1).Trim_value-10);
             app.TrimValueLabel_2.Text = int2str(app.EV(2).Trim_value-10);
             app.TrimValueLabel_3.Text = int2str(app.EV(3).Trim_value-10);
             app.TrimValueLabel_4.Text = int2str(app.EV(4).Trim_value-10);
            

             updateLog(app, 'Stop. Calculating results.' );

             try
                [C1, C2, C3] = EV3_eval(app.timestamp(app.EVMonitored,:), app.RLI(app.EVMonitored,:), app.Ultrasonic(app.EVMonitored,:));
                app.C1Tag.Text = int2str(C1);
                app.C2Tag.Text = int2str(C2);
                app.C3Tag.Text = int2str(C3);
                app.TimeTag.Text = int2str(app.timestamp(end));
             catch
             end
             if app.EVConnected(5) == 1
                [C1, C2, ~] = EV3_eval(app.timestamp(5,:), app.RLI(5,:), zeros(1,length(app.RLI)));
                app.C1Tag_EV5.Text = int2str(C1);
                app.C2Tag_EV5.Text = int2str(C2);
                app.TimeTag_EV5.Text = int2str(app.timestamp(5,end));
             end
             
        end

        % Value changed function: EV1DropDown
        function EV1DropDownValueChanged(app, event)
            value = app.EV1DropDown.Value;
            ev = 1;
            
            ChangeMode(app, ev,value);
            
            %send new status to EV1
            SendMessage(app,1);

        end

        % Value changed function: EV2DropDown
        function EV2DropDownValueChanged(app, event)
            value = app.EV2DropDown.Value;
            ev=2;
            
            ChangeMode(app, ev,value);
            
            %Send new status to EV2
            SendMessage(app,2);

        end

        % Button pushed function: LeftTrimButton_1
        function LeftTrimButton_1Pushed(app, event)
            display = LeftTrimValueChange(app,1);
            app.TrimValueLabel_1.Text = display;
       
            %send new status to EV1
            SendMessage(app,1);

        end

        % Button pushed function: RightTrimButton_1
        function RightTrimButton_1Pushed(app, event)
            display = RightTrimValueChange(app,1);
            app.TrimValueLabel_1.Text = display;
            
            %Send new status to EV1
            SendMessage(app,1);

        end

        % Value changed function: EVDropDown
        function EVDropDownValueChanged(app, event)
            value = app.EVDropDown.Value;
            switch value
                case  "EV1"
                    app.EV_readings_display = 1;
                    app.EVMonitored = 1;
                case "EV2"
                    app.EV_readings_display = 2;
                    app.EVMonitored = 2;
                case "EV3"
                    app.EV_readings_display = 3;
                    app.EVMonitored = 3;
                case "EV4"
                    app.EV_readings_display = 4;
                    app.EVMonitored = 4;
            end
        end

        % Value changed function: BaseSpeedSlider_1, 
        % PlatooningDistanceSlider_1
        function BaseSpeedSliderValueChanged(app, event)
            app.EV(1).BaseSpeed = app.BaseSpeedSlider_1.Value;
            app.EV(1).PlatooningDistance = app.PlatooningDistanceSlider_1.Value;
            SendMessage(app,1);
            
        end

        % Value changed function: BaseSpeedSlider_2, 
        % PlatooningDistanceSlider_2
        function PlatooningDistanceSlider_2ValueChanged(app, event)
            app.EV(2).BaseSpeed = app.BaseSpeedSlider_2.Value;
            app.EV(2).PlatooningDistance = app.PlatooningDistanceSlider_2.Value;
            SendMessage(app,2);
            
        end

        % Selection changed function: LeaderButtonGroup
        function LeaderButtonGroupSelectionChanged(app, event)
            selectedButton = app.LeaderButtonGroup.SelectedObject;
            switch selectedButton
                case app.EV1Button
                    app.Leader = [1 0 0 0];
                case app.EV2Button
                    app.Leader = [0 1 0 0];
                case app.EV3Button
                    app.Leader = [0 0 1 0];
                case app.EV4Button
                    app.Leader = [0 0 0 1];
                case app.NoneButton
                    app.Leader = [0 0 0 0];
            end
            for ev=1:4
                SendMessage(app,ev);
            end


        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            if app.Switch_3.Value == "On"
                
                 ConnectEV(app,3, app.EV3_IP)
                 app.EV3Lamp.Color = 'g';
                 updateLog(app,'EV3 connected.');
                 PlaySound(app,'luigi.wav');   
                    
            elseif app.Switch_3.Value == "Off"
                
                DisconnectEV(app,3)
                
                app.EV3Lamp.Color = 'r';
                updateLog(app,'EV3 disconnected.');
                PlaySound(app,'disconnect.wav');
                
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            if app.Switch_4.Value == "On"
                ConnectEV(app,4, app.EV4_IP)
                app.EV4Lamp.Color = 'g';
                updateLog(app,'EV4 connected.');
                PlaySound(app,'toad.wav');


            elseif app.Switch_4.Value == "Off"
                
                DisconnectEV(app,4)
                
                app.EV4Lamp.Color = 'r';
                updateLog(app, 'EV4 disconnected.');
                PlaySound(app,'disconnect.wav');
                
                
            end
        end

        % Button pushed function: LeftTrimButton_2
        function LeftTrimButton_2Pushed(app, event)
            display = LeftTrimValueChange(app,2);
            app.TrimValueLabel_2.Text = display;
       
            %send new status to EV2
            SendMessage(app,2);
        end

        % Button pushed function: LeftTrimButton_3
        function LeftTrimButton_3Pushed(app, event)
            display = LeftTrimValueChange(app,3);
            app.TrimValueLabel_3.Text = display;
       
            %send new status to EV3
            SendMessage(app,3);
        end

        % Button pushed function: LeftTrimButton_4
        function LeftTrimButton_4Pushed(app, event)
            display = LeftTrimValueChange(app,4);
            app.TrimValueLabel_4.Text = display;
       
            %send new status to EV4
            SendMessage(app,4);
        end

        % Button pushed function: RightTrimButton_2
        function RightTrimButton_2Pushed(app, event)
            display = RightTrimValueChange(app,2);
            app.TrimValueLabel_2.Text = display;
            
            %Send new status to EV2
            SendMessage(app,2);
        end

        % Button pushed function: RightTrimButton_3
        function RightTrimButton_3Pushed(app, event)
            display = RightTrimValueChange(app,3);
            app.TrimValueLabel_3.Text = display;
            
            %Send new status to EV3
            SendMessage(app,3);
        end

        % Button pushed function: RightTrimButton_4
        function RightTrimButton_4Pushed(app, event)
            display = RightTrimValueChange(app,4);
            app.TrimValueLabel_4.Text = display;
            
            %Send new status to EV4
            SendMessage(app,4);
        end

        % Value changed function: BaseSpeedSlider_3, 
        % PlatooningDistanceSlider_3
        function BaseSpeedSlider_3ValueChanged(app, event)
            app.EV(3).BaseSpeed = app.BaseSpeedSlider_3.Value;
            app.EV(3).PlatooningDistance = app.PlatooningDistanceSlider_3.Value;
            SendMessage(app,3);
            
        end

        % Value changed function: BaseSpeedSlider_4, 
        % PlatooningDistanceSlider_4
        function BaseSpeedSlider_4ValueChanged(app, event)
            app.EV(4).BaseSpeed = app.BaseSpeedSlider_4.Value;
            app.EV(4).PlatooningDistance = app.PlatooningDistanceSlider_4.Value;
            SendMessage(app,4);
            
        end

        % Value changed function: EV3DropDown
        function EV3DropDownValueChanged(app, event)
            value = app.EV3DropDown.Value;
            ev=3;
            
            ChangeMode(app, ev,value);
            
            %Send new status to EV3
            SendMessage(app,3);
        end

        % Value changed function: EV4DropDown
        function EV4DropDownValueChanged(app, event)
            value = app.EV4DropDown.Value;
            ev=4;
            
            ChangeMode(app, ev,value);
            
            %Send new status to EV4
            SendMessage(app,4);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            if app.Switch_5.Value == "On"
                
                 ConnectEV(app,5, app.EV5_IP)
                 disp(app.EVConnected(5))
                 app.EV5Lamp.Color = 'g';
                 updateLog(app,'EV5 connected.'); 
                 PlaySound(app,'wario.wav');
                  
                    
            elseif app.Switch_5.Value == "Off"
                
                DisconnectEV(app,5)
                
                app.EV5Lamp.Color = 'r';
                updateLog(app,'EV5 disconnected.');
                PlaySound(app,'disconnect.wav');
                
            end
            
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            if app.Switch_6.Value == "On"
                
                 ConnectEV(app,6, app.EV6_IP)
                 app.EV6Lamp.Color = 'g';
                 updateLog(app,'EV6 connected.');  
                 PlaySound(app,'bowser.wav');
                    
            elseif app.Switch_6.Value == "Off"
                
                DisconnectEV(app,6)
                
                app.EV6Lamp.Color = 'r';
                updateLog(app,'EV6 disconnected.');
                PlaySound(app,'disconnect.wav');
                
            end
            
        end

        % Value changed function: BaseSpeedSlider_5
        function BaseSpeedSlider_5ValueChanged(app, event)

            app.EV(5).BaseSpeed = app.BaseSpeedSlider_5.Value;
            SendMessage(app,5);
        end

        % Value changed function: BaseSpeedSlider_6
        function BaseSpeedSlider_6ValueChanged(app, event)
            app.EV(6).BaseSpeed = app.BaseSpeedSlider_6.Value;
            SendMessage(app,6);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Evscontrol = uitabgroup(app.ControlPanel);
            app.EV1Tab = uitab(app.Evscontrol);
            app.EV1Tab.Title = 'EV1';
            app.TrimPanel_1 = uipanel(app.EV1Tab);
            app.TrimPanel_1.Title = 'Trim';
            app.TrimValueLabel_1 = uilabel(app.TrimPanel_1);
            app.TrimValueLabel_1.Text = '00';
            app.LeftTrimButton_1 = uibutton(app.TrimPanel_1, 'push');
            app.LeftTrimButton_1.ButtonPushedFcn = createCallbackFcn(app, @LeftTrimButton_1Pushed, true);
            app.LeftTrimButton_1.Text = 'Left';
            app.RightTrimButton_1 = uibutton(app.TrimPanel_1, 'push');
            app.RightTrimButton_1.ButtonPushedFcn = createCallbackFcn(app, @RightTrimButton_1Pushed, true);
            app.RightTrimButton_1.Text = 'Right';
            app.PlatooningDistanceLabel = uilabel(app.EV1Tab);
            app.PlatooningDistanceLabel.Text = 'Platooning Distance';
            app.PlatooningDistanceSlider_1 = uislider(app.EV1Tab);
            app.PlatooningDistanceSlider_1.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSliderValueChanged, true);
            app.PlatooningDistanceSlider_1.Value = 20;
            app.BaseSpeedLabel = uilabel(app.EV1Tab);
            app.BaseSpeedLabel.Text = 'Base Speed';
            app.BaseSpeedSlider_1 = uislider(app.EV1Tab);
            app.BaseSpeedSlider_1.MajorTicks = [0 25 50 75 100];
            app.BaseSpeedSlider_1.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSliderValueChanged, true);
            app.BaseSpeedSlider_1.Value = 25;
            app.EV2Tab = uitab(app.Evscontrol);
            app.EV2Tab.Title = 'EV2';
            app.TrimPanel_2 = uipanel(app.EV2Tab);
            app.TrimPanel_2.Title = 'Trim';
            app.TrimValueLabel_2 = uilabel(app.TrimPanel_2);
            app.TrimValueLabel_2.Text = '00';
            app.LeftTrimButton_2 = uibutton(app.TrimPanel_2, 'push');
            app.LeftTrimButton_2.ButtonPushedFcn = createCallbackFcn(app, @LeftTrimButton_2Pushed, true);
            app.LeftTrimButton_2.Text = 'Left';
            app.RightTrimButton_2 = uibutton(app.TrimPanel_2, 'push');
            app.RightTrimButton_2.ButtonPushedFcn = createCallbackFcn(app, @RightTrimButton_2Pushed, true);
            app.RightTrimButton_2.Text = 'Right';
            app.BaseSpeedSlider_2Label = uilabel(app.EV2Tab);
            app.BaseSpeedSlider_2Label.Text = 'Base Speed';
            app.BaseSpeedSlider_2 = uislider(app.EV2Tab);
            app.BaseSpeedSlider_2.MajorTicks = [0 25 50 75 100];
            app.BaseSpeedSlider_2.ValueChangedFcn = createCallbackFcn(app, @PlatooningDistanceSlider_2ValueChanged, true);
            app.BaseSpeedSlider_2.Value = 25;
            app.PlatooningDistanceSlider_2Label = uilabel(app.EV2Tab);
            app.PlatooningDistanceSlider_2Label.Text = 'Platooning Distance';
            app.PlatooningDistanceSlider_2 = uislider(app.EV2Tab);
            app.PlatooningDistanceSlider_2.ValueChangedFcn = createCallbackFcn(app, @PlatooningDistanceSlider_2ValueChanged, true);
            app.PlatooningDistanceSlider_2.Value = 20;
            app.EV3Tab = uitab(app.Evscontrol);
            app.EV3Tab.Title = 'EV3';
            app.TrimPanel_3 = uipanel(app.EV3Tab);
            app.TrimPanel_3.Title = 'Trim';
            app.TrimValueLabel_3 = uilabel(app.TrimPanel_3);
            app.TrimValueLabel_3.Text = '00';
            app.LeftTrimButton_3 = uibutton(app.TrimPanel_3, 'push');
            app.LeftTrimButton_3.ButtonPushedFcn = createCallbackFcn(app, @LeftTrimButton_3Pushed, true);
            app.LeftTrimButton_3.Text = 'Left';
            app.RightTrimButton_3 = uibutton(app.TrimPanel_3, 'push');
            app.RightTrimButton_3.ButtonPushedFcn = createCallbackFcn(app, @RightTrimButton_3Pushed, true);
            app.RightTrimButton_3.Text = 'Right';
            app.PlatooningDistanceSlider_3Label = uilabel(app.EV3Tab);
            app.PlatooningDistanceSlider_3Label.Text = 'Platooning Distance';
            app.PlatooningDistanceSlider_3 = uislider(app.EV3Tab);
            app.PlatooningDistanceSlider_3.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSlider_3ValueChanged, true);
            app.PlatooningDistanceSlider_3.Value = 20;
            app.BaseSpeedSlider_3Label = uilabel(app.EV3Tab);
            app.BaseSpeedSlider_3Label.Text = 'Base Speed';
            app.BaseSpeedSlider_3 = uislider(app.EV3Tab);
            app.BaseSpeedSlider_3.MajorTicks = [0 25 50 75 100];
            app.BaseSpeedSlider_3.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSlider_3ValueChanged, true);
            app.BaseSpeedSlider_3.Value = 25;
            app.EV4Tab = uitab(app.Evscontrol);
            app.EV4Tab.Title = 'EV4';
            app.TrimPanel_4 = uipanel(app.EV4Tab);
            app.TrimPanel_4.Title = 'Trim';
            app.TrimValueLabel_4 = uilabel(app.TrimPanel_4);
            app.TrimValueLabel_4.Text = '00';
            app.LeftTrimButton_4 = uibutton(app.TrimPanel_4, 'push');
            app.LeftTrimButton_4.ButtonPushedFcn = createCallbackFcn(app, @LeftTrimButton_4Pushed, true);
            app.LeftTrimButton_4.Text = 'Left';
            app.RightTrimButton_4 = uibutton(app.TrimPanel_4, 'push');
            app.RightTrimButton_4.ButtonPushedFcn = createCallbackFcn(app, @RightTrimButton_4Pushed, true);
            app.RightTrimButton_4.Text = 'Right';
            app.PlatooningDistanceSlider_4Label = uilabel(app.EV4Tab);
            app.PlatooningDistanceSlider_4Label.Text = 'Platooning Distance';
            app.PlatooningDistanceSlider_4 = uislider(app.EV4Tab);
            app.PlatooningDistanceSlider_4.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSlider_4ValueChanged, true);
            app.PlatooningDistanceSlider_4.Value = 20;
            app.BaseSpeedSlider_4Label = uilabel(app.EV4Tab);
            app.BaseSpeedSlider_4Label.Text = 'Base Speed';
            app.BaseSpeedSlider_4 = uislider(app.EV4Tab);
            app.BaseSpeedSlider_4.MajorTicks = [0 25 50 75 100];
            app.BaseSpeedSlider_4.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSlider_4ValueChanged, true);
            app.BaseSpeedSlider_4.Value = 25;
            app.EV5Tab = uitab(app.Evscontrol);
            app.EV5Tab.Title = 'EV5';
            app.BaseSpeedSlider_5Label = uilabel(app.EV5Tab);
            app.BaseSpeedSlider_5Label.Text = 'Base Speed';
            app.BaseSpeedSlider_5 = uislider(app.EV5Tab);
            app.BaseSpeedSlider_5.MajorTicks = [0 25 50 75 100];
            app.BaseSpeedSlider_5.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSlider_5ValueChanged, true);
            app.BaseSpeedSlider_5.Value = 85;
            app.TimeTag_EV5 = uilabel(app.EV5Tab);
            app.TimeTag_EV5.Text = '00';
            app.C1Tag_EV5 = uilabel(app.EV5Tab);
            app.C1Tag_EV5.Text = '00';
            app.C2Tag_EV5 = uilabel(app.EV5Tab);
            app.C2Tag_EV5.Text = '00';
            app.TimeLabel_EV5 = uilabel(app.EV5Tab);
            app.TimeLabel_EV5.Text = 'Time';
            app.C1Label_EV5 = uilabel(app.EV5Tab);
            app.C1Label_EV5.Text = 'C1';
            app.C2Label_EV5 = uilabel(app.EV5Tab);
            app.C2Label_EV5.Text = 'C2';
            app.EV6Tab = uitab(app.Evscontrol);
            app.EV6Tab.Title = 'EV6';
            app.BaseSpeedSlider_6Label = uilabel(app.EV6Tab);
            app.BaseSpeedSlider_6Label.Text = 'Base Speed';
            app.BaseSpeedSlider_6 = uislider(app.EV6Tab);
            app.BaseSpeedSlider_6.MajorTicks = [0 25 50 75 100];
            app.BaseSpeedSlider_6.ValueChangedFcn = createCallbackFcn(app, @BaseSpeedSlider_6ValueChanged, true);
            app.BaseSpeedSlider_6.Value = 60;
            app.SpathTab = uitab(app.Evscontrol);
            app.SpathTab.Title = 'S path';
            app.DestinationDropDownLabel = uilabel(app.SpathTab);
            app.DestinationDropDownLabel.Text = 'Destination';
            app.DestinationDropDown = uidropdown(app.SpathTab);
            app.DestinationDropDown.Items = {'1', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.DestinationDropDown.Value = '1';
            app.ModePanel = uipanel(app.ControlPanel);
            app.ModePanel.Title = 'Mode';
            app.EV1DropDownLabel = uilabel(app.ModePanel);
            app.EV1DropDownLabel.Text = 'EV1';
            app.EV1DropDown = uidropdown(app.ModePanel);
            app.EV1DropDown.Items = {'Platooning', 'Path Tracking', 'Parking', 'Shortest Path'};
            app.EV1DropDown.ValueChangedFcn = createCallbackFcn(app, @EV1DropDownValueChanged, true);
            app.EV1DropDown.Value = 'Path Tracking';
            app.EV2DropDownLabel = uilabel(app.ModePanel);
            app.EV2DropDownLabel.Text = 'EV2';
            app.EV2DropDown = uidropdown(app.ModePanel);
            app.EV2DropDown.Items = {'Platooning', 'Path Tracking', 'Parking'};
            app.EV2DropDown.ValueChangedFcn = createCallbackFcn(app, @EV2DropDownValueChanged, true);
            app.EV2DropDown.Value = 'Path Tracking';
            app.EV3DropDownLabel = uilabel(app.ModePanel);
            app.EV3DropDownLabel.Text = 'EV3';
            app.EV3DropDown = uidropdown(app.ModePanel);
            app.EV3DropDown.Items = {'Platooning', 'Path Tracking', 'Parking', 'Shortest Path'};
            app.EV3DropDown.ValueChangedFcn = createCallbackFcn(app, @EV3DropDownValueChanged, true);
            app.EV3DropDown.Value = 'Path Tracking';
            app.EV4DropDownLabel = uilabel(app.ModePanel);
            app.EV4DropDownLabel.Text = 'EV4';
            app.EV4DropDown = uidropdown(app.ModePanel);
            app.EV4DropDown.Items = {'Platooning', 'Path Tracking', 'Parking', 'Shortest Path'};
            app.EV4DropDown.ValueChangedFcn = createCallbackFcn(app, @EV4DropDownValueChanged, true);
            app.EV4DropDown.Value = 'Path Tracking';
            app.LeaderButtonGroup = uibuttongroup(app.ControlPanel);
            app.LeaderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeaderButtonGroupSelectionChanged, true);
            app.LeaderButtonGroup.Title = 'Leader';
            app.EV1Button = uitogglebutton(app.LeaderButtonGroup);
            app.EV1Button.Text = 'EV1';
            app.EV2Button = uitogglebutton(app.LeaderButtonGroup);
            app.EV2Button.Text = 'EV2';
            app.EV3Button = uitogglebutton(app.LeaderButtonGroup);
            app.EV3Button.Text = 'EV3';
            app.EV4Button = uitogglebutton(app.LeaderButtonGroup);
            app.EV4Button.Text = 'EV4';
            app.NoneButton = uitogglebutton(app.LeaderButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.Connections = uitabgroup(app.ControlPanel);
            app.ConnectionsTab = uitab(app.Connections);
            app.ConnectionsTab.Title = 'Connections';
            app.Switch_1 = uiswitch(app.ConnectionsTab, 'rocker');
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @Switch_1ValueChanged, true);
            app.Switch_2 = uiswitch(app.ConnectionsTab, 'rocker');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Mario = uiaxes(app.ConnectionsTab);
            title(app.Mario, 'Title')
            xlabel(app.Mario, 'X')
            ylabel(app.Mario, 'Y')
            app.Toad = uiaxes(app.ConnectionsTab);
            title(app.Toad, 'Title')
            xlabel(app.Toad, 'X')
            ylabel(app.Toad, 'Y')
            app.Switch_3 = uiswitch(app.ConnectionsTab, 'rocker');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_4 = uiswitch(app.ConnectionsTab, 'rocker');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Yoshi = uiaxes(app.ConnectionsTab);
            title(app.Yoshi, 'Title')
            xlabel(app.Yoshi, 'X')
            ylabel(app.Yoshi, 'Y')
            app.Luigi = uiaxes(app.ConnectionsTab);
            title(app.Luigi, 'Title')
            xlabel(app.Luigi, 'X')
            ylabel(app.Luigi, 'Y')
            app.EV1Lamp = uilamp(app.ConnectionsTab);
            app.EV2Lamp = uilamp(app.ConnectionsTab);
            app.EV3Lamp = uilamp(app.ConnectionsTab);
            app.EV4Lamp = uilamp(app.ConnectionsTab);
            app.SecondaryTab = uitab(app.Connections);
            app.SecondaryTab.Title = 'Secondary';
            app.Switch_5 = uiswitch(app.SecondaryTab, 'rocker');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_6 = uiswitch(app.SecondaryTab, 'rocker');
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Label_2 = uilabel(app.SecondaryTab);
            app.Label_2.Text = '';
            app.EV5Lamp = uilamp(app.SecondaryTab);
            app.Label = uilabel(app.SecondaryTab);
            app.Label.Text = '';
            app.EV6Lamp = uilamp(app.SecondaryTab);
            app.Wario = uiaxes(app.SecondaryTab);
            title(app.Wario, 'Title')
            xlabel(app.Wario, 'X')
            ylabel(app.Wario, 'Y')
            app.Bowser = uiaxes(app.SecondaryTab);
            title(app.Bowser, 'Title')
            xlabel(app.Bowser, 'X')
            ylabel(app.Bowser, 'Y')
            app.ReadingsPanel = uipanel(app.UIFigure);
            app.ReadingsPanel.Title = 'Readings';
            app.SensorsPanel = uipanel(app.ReadingsPanel);
            app.SensorsPanel.Title = 'Sensors';
            app.LeftSensorLabel = uilabel(app.SensorsPanel);
            app.LeftSensorLabel.Text = 'Left';
            app.MiddleSensorLabel = uilabel(app.SensorsPanel);
            app.MiddleSensorLabel.Text = 'Middle';
            app.RightSensorLabel = uilabel(app.SensorsPanel);
            app.RightSensorLabel.Text = 'Right';
            app.RightSensorValue = uilabel(app.SensorsPanel);
            app.RightSensorValue.Text = '00';
            app.UltrasonicGaugeLabel = uilabel(app.SensorsPanel);
            app.UltrasonicGaugeLabel.Text = 'Ultrasonic';
            app.UltrasonicGauge = uigauge(app.SensorsPanel, 'linear');
            app.UltrasonicGauge.Limits = [0 255];
            app.UltrasonicGauge.MajorTicks = [0 50 100 150 200 255];
            app.MidLamp = uilamp(app.SensorsPanel);
            app.LeftSensorValue = uilabel(app.SensorsPanel);
            app.LeftSensorValue.Text = '00';
            app.EVDropDownLabel = uilabel(app.ReadingsPanel);
            app.EVDropDownLabel.Text = 'EV';
            app.EVDropDown = uidropdown(app.ReadingsPanel);
            app.EVDropDown.Items = {'EV1', 'EV2', 'EV3', 'EV4'};
            app.EVDropDown.ValueChangedFcn = createCallbackFcn(app, @EVDropDownValueChanged, true);
            app.EVDropDown.Value = 'EV1';
            app.battery_image = uiaxes(app.ReadingsPanel);
            app.TimeTag = uilabel(app.ReadingsPanel);
            app.TimeTag.Text = '00';
            app.C1Tag = uilabel(app.ReadingsPanel);
            app.C1Tag.Text = '00';
            app.C2Tag = uilabel(app.ReadingsPanel);
            app.C2Tag.Text = '00';
            app.C3Tag = uilabel(app.ReadingsPanel);
            app.C3Tag.Text = '00';
            app.TimeLabel = uilabel(app.ReadingsPanel);
            app.TimeLabel.Text = 'Time';
            app.C1Label = uilabel(app.ReadingsPanel);
            app.C1Label.Text = 'C1';
            app.C2Label = uilabel(app.ReadingsPanel);
            app.C2Label.Text = 'C2';
            app.C3Label = uilabel(app.ReadingsPanel);
            app.C3Label.Text = 'C3';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = v5_exam3_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end